classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KUKAZSpinner              matlab.ui.control.Spinner
        KUKAZSpinnerLabel         matlab.ui.control.Label
        KUKAYSpinner              matlab.ui.control.Spinner
        KUKAYSpinnerLabel         matlab.ui.control.Label
        KUKAXSpinner              matlab.ui.control.Spinner
        KUKAXSpinnerLabel         matlab.ui.control.Label
        DoBotCompleteTaskAButton  matlab.ui.control.Button
        DoBotCompleteTaskBButton  matlab.ui.control.Button
        KUKACompleteTaskBButton   matlab.ui.control.Button
        KUKACompleteTaskAButton   matlab.ui.control.Button
        ResetCheckBox             matlab.ui.control.CheckBox
        EMERGENCYSTOPButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelKUKA % KUKA model location
        modelDoBot % DoBot model location
        qn_K = zeros(1,7);
        qn_D = zeros(1,4);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            close all
            figure;
            app.modelKUKA = KUKAiiwa7;
            app.modelKUKA.model.animate(app.qn_K)
            drawnow();
            Tr = app.modelKUKA.model.fkine(app.modelKUKA.model.getpos).T;
            UpdateSpinnerValues(Tr,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Button pushed function: KUKACompleteTaskAButton
        function KUKACompleteTaskAButtonPushed(app, event)
            q1 = app.modelKUKA.model.getpos;
            q2 = [2.4511   -0.8651    2.3865   -1.2566    1.1868    1.0891   -1.2828];
            qMatrix = jtraj(q1,q2,100);
            for i = 1:size(qMatrix,1)
                app.modelKUKA.model.plot(qMatrix(i,:))
                UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(qMatrix(i,:)),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            end
        end

        % Button pushed function: KUKACompleteTaskBButton
        function KUKACompleteTaskBButtonPushed(app, event)
            q1 = app.modelKUKA.model.getpos;
            q2 = [1.1610    1.2566   -1.1868    1.0891    2.3736    0.8378    1.2217];
            qMatrix = jtraj(q1,q2,100);
            app.modelKUKA.model.animate(qMatrix)
        end

        % Value changed function: KUKAXSpinner
        function KUKAXSpinnerValueChanged(app, event)
            value = app.KUKAXSpinner.Value;
            RMRC_oneDirection(app.modelKUKA,10,value/100,1)
        end

        % Value changed function: KUKAYSpinner
        function KUKAYSpinnerValueChanged(app, event)
            value = app.KUKAYSpinner.Value;
            RMRC_oneDirection(app.modelKUKA,10,value/100,2)
        end

        % Value changed function: KUKAZSpinner
        function KUKAZSpinnerValueChanged(app, event)
            value = app.KUKAZSpinner.Value;
            RMRC_oneDirection(app.modelKUKA,10,value/100,3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.ResetCheckBox = uicheckbox(app.UIFigure);
            app.ResetCheckBox.Text = 'Reset';
            app.KUKACompleteTaskAButton = uibutton(app.UIFigure, 'push');
            app.KUKACompleteTaskAButton.ButtonPushedFcn = createCallbackFcn(app, @KUKACompleteTaskAButtonPushed, true);
            app.KUKACompleteTaskAButton.Text = {'KUKA'; 'Complete Task A'};
            app.KUKACompleteTaskBButton = uibutton(app.UIFigure, 'push');
            app.KUKACompleteTaskBButton.ButtonPushedFcn = createCallbackFcn(app, @KUKACompleteTaskBButtonPushed, true);
            app.KUKACompleteTaskBButton.Text = {'KUKA'; 'Complete Task B'};
            app.DoBotCompleteTaskBButton = uibutton(app.UIFigure, 'push');
            app.DoBotCompleteTaskBButton.Text = {'DoBot'; 'Complete Task B'};
            app.DoBotCompleteTaskAButton = uibutton(app.UIFigure, 'push');
            app.DoBotCompleteTaskAButton.Text = {'DoBot'; 'Complete Task A'};
            app.KUKAXSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAXSpinnerLabel.Text = 'KUKA X';
            app.KUKAXSpinner = uispinner(app.UIFigure);
            app.KUKAXSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAXSpinnerValueChanged, true);
            app.KUKAYSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAYSpinnerLabel.Text = 'KUKA Y';
            app.KUKAYSpinner = uispinner(app.UIFigure);
            app.KUKAYSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAYSpinnerValueChanged, true);
            app.KUKAZSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAZSpinnerLabel.Text = 'KUKA Z';
            app.KUKAZSpinner = uispinner(app.UIFigure);
            app.KUKAZSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAZSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end